classdef ABregulate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        BLabel               matlab.ui.control.Label
        BSlider              matlab.ui.control.Slider
        Signal1              matlab.ui.control.UIAxes
        NEditFieldLabel      matlab.ui.control.Label
        NEditField           matlab.ui.control.NumericEditField
        tstepEditFieldLabel  matlab.ui.control.Label
        tstepEditField       matlab.ui.control.NumericEditField
        tmaxEditFieldLabel   matlab.ui.control.Label
        tmaxEditField        matlab.ui.control.NumericEditField
        thSliderLabel        matlab.ui.control.Label
        thSlider             matlab.ui.control.Slider
        whSlider_2Label      matlab.ui.control.Label
        whSlider_2           matlab.ui.control.Slider
        B0SliderLabel        matlab.ui.control.Label
        B0Slider             matlab.ui.control.Slider
        GsLabel              matlab.ui.control.Label
    end


    properties (Access = public)
        %全局数据
        t
        tstep
        wl
        N
        %输入核的标准数据
        S           %Dependent
        wh
        th
        Px_signal0   % Dependent
        t_signal0     % Dependent
        Px0          %Dependent
        A0           %Dependent
        B0            %Dependent
        beta0        %Dependent
        %拟合数据
        S1
        B1          %Dependent
        Px_signal1    %Dependent
    end

    methods (Access = public)
    
        function RenewPlot(app)
            app.Signal1.cla();
            hold(app.Signal1,'on');
            plot(app.Signal1,app.t,app.S.Px,'color','r');
            plot(app.Signal1,app.t,app.S1.Px,'color','b');
            scatter(app.Signal1,app.t_signal0,app.Px_signal0...
                ,'MarkerEdgeColor','r');
            scatter(app.Signal1,app.t_signal0,app.Px_signal1,...
                'MarkerEdgeColor','b');
            app.Signal1.XLim = [0 app.t(end)];
            app.Signal1.YLim = [0 1];
        end
        
        function RenewAll(app)
            app.RenewS();
            app.RenewS1();
            app.A0 = app.wh.*cos(app.th);
            app.B0 = app.wh.*sin(app.th);
            app.beta0 = sqrt((2*pi*app.wh/app.wl).^2+2*(2*pi*app.wh/app.wl)*cos(app.th)+1);
            app.Px0 = app.S.Px;
            [app.t_signal0,app.Px_signal0]=Peak_seperate(app.t,app.Px0);
            app.Px_signal1 = 1-Single_peakdepth(app.B1,app.wl,app.beta0,...
                app.N,app.t_signal0);
            app.RenewPlot();
        end
        
        function RenewS(app)
           app.S = Kernal(app.wh,app.th,app.wl,app.N,app.t);
           app.S.get_Px();
        end
        
        function RenewS1(app)
            wh1 = sqrt(app.A0.^2+app.B1.^2);
            th1 = atan(app.B1./app.A0);
            app.S1 = Kernal(wh1,th1,app.wl,app.N,app.t);
            app.S1.get_Px();
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %初始状态，计算Px,t_signal,Px_signal
            app.wl = 400*2*pi*10.7083e-4;
            app.th = pi*21/180;
            app.wh = 1e-3*83;
            app.N = 56;
            app.t = (0.001:0.001:10)';
            app.tstep = app.t(2)-app.t(1);
            app.S = Kernal(app.wh,app.th,app.wl,app.N,app.t);
            app.S.get_Px();
            app.Px0 = app.S.Px;
            [~,app.t_signal0,app.Px_signal0] = Peak_seperate_for_app(app.t,app.Px0);
            app.A0 = app.wh.*cos(app.th);
            app.B0 = app.wh.*sin(app.th);
            app.B1 = 0;
            app.beta0 = sqrt((2*pi*app.wh/app.wl).^2+2*(2*pi*app.wh/app.wl)*cos(app.th)+1);
%             app.Px_signal0 = Single_peakdepth(app.B0,app.wl,app.beta0,...
%                 app.N,app.t_signal0);
            hold(app.Signal1,'on');
            scatter(app.Signal1,app.t_signal0,app.Px_signal0);
            plot(app.Signal1,app.t,app.S.Px,'color','r');
            app.Signal1.XLim = [0,app.t(end)];
            app.Signal1.YLim = [0,1];
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            value = app.BSlider.Value;
            app.B1 = value;
            app.Px_signal1 = 1-Single_peakdepth(app.B1,app.wl,app.beta0,...
                app.N,app.t_signal0);
            %使用A0和B1建一个核并画出图像
            wh1 = sqrt(app.A0.^2+app.B1.^2);
            th1 = atan(app.B1./app.A0);
            app.S1 = Kernal(wh1,th1,app.wl,app.N,app.t);
            app.S1.get_Px();
            app.RenewPlot();
        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)
            value = app.NEditField.Value;
            app.N = value;
            app.RenewAll();
        end

        % Value changed function: tstepEditField
        function tstepEditFieldValueChanged(app, event)
            value = app.tstepEditField.Value;
            app.tstep = value;
            tm = app.t(end);
            app.t = (app.tstep:app.tstep:tm)';
            app.RenewAll();
        end

        % Value changed function: tmaxEditField
        function tmaxEditFieldValueChanged(app, event)
            value = app.tmaxEditField.Value;
            app.t = (app.tstep:app.tstep:value)';
            app.RenewAll();
        end

        % Value changed function: whSlider_2
        function whSlider_2ValueChanged(app, event)
            value = app.whSlider_2.Value;
            app.wh = 1e-3*value;
            app.RenewAll();
        end

        % Value changed function: thSlider
        function thSliderValueChanged(app, event)
            value = app.thSlider.Value;
            app.th = pi*value/180;
            app.RenewAll();
        end

        % Value changed function: B0Slider
        function B0SliderValueChanged(app, event)
            value = app.B0Slider.Value;
            app.wl = 2*pi*10.7083e-4*value;
            app.RenewAll();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B(参数)';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [0 0.05];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.Signal1 = uiaxes(app.UIFigure);
            title(app.Signal1, 'B-regulate')
            xlabel(app.Signal1, 't')
            ylabel(app.Signal1, 'signal')
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Value = 56;
            app.tstepEditFieldLabel = uilabel(app.UIFigure);
            app.tstepEditFieldLabel.Text = 'tstep';
            app.tstepEditField = uieditfield(app.UIFigure, 'numeric');
            app.tstepEditField.ValueChangedFcn = createCallbackFcn(app, @tstepEditFieldValueChanged, true);
            app.tstepEditField.Value = 0.001;
            app.tmaxEditFieldLabel = uilabel(app.UIFigure);
            app.tmaxEditFieldLabel.Text = 'tmax';
            app.tmaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.tmaxEditField.ValueChangedFcn = createCallbackFcn(app, @tmaxEditFieldValueChanged, true);
            app.tmaxEditField.Value = 10;
            app.thSliderLabel = uilabel(app.UIFigure);
            app.thSliderLabel.Text = 'th';
            app.thSlider = uislider(app.UIFigure);
            app.thSlider.Limits = [0 180];
            app.thSlider.ValueChangedFcn = createCallbackFcn(app, @thSliderValueChanged, true);
            app.thSlider.Value = 21;
            app.whSlider_2Label = uilabel(app.UIFigure);
            app.whSlider_2Label.Text = 'wh';
            app.whSlider_2 = uislider(app.UIFigure);
            app.whSlider_2.ValueChangedFcn = createCallbackFcn(app, @whSlider_2ValueChanged, true);
            app.whSlider_2.Value = 83;
            app.B0SliderLabel = uilabel(app.UIFigure);
            app.B0SliderLabel.Text = 'B0(磁场)';
            app.B0Slider = uislider(app.UIFigure);
            app.B0Slider.Limits = [0 1000];
            app.B0Slider.ValueChangedFcn = createCallbackFcn(app, @B0SliderValueChanged, true);
            app.B0Slider.Value = 400;
            app.GsLabel = uilabel(app.UIFigure);
            app.GsLabel.Text = 'Gs';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ABregulate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end