classdef Base_correct < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        T2SliderLabel     matlab.ui.control.Label
        T2Slider          matlab.ui.control.Slider
        T1SliderLabel     matlab.ui.control.Label
        T1Slider          matlab.ui.control.Slider
        T1EditFieldLabel  matlab.ui.control.Label
        T1EditField       matlab.ui.control.NumericEditField
        T2EditFieldLabel  matlab.ui.control.Label
        T2EditField       matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        t
        Px
        T1
        T2
        P_base
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, t0, Px0)
            app.T1 = 100;
            app.T2 = 100;
            app.Px = Px0;
            app.t = t0;
            plot(app.UIAxes,app.t,app.Px);
        end

        % Value changed function: T2Slider
        function T2SliderValueChanged(app, event)
            app.T2 = app.T2Slider.Value;
            app.P_base = 1/2*exp(-2*app.t/(app.T1))+1/6*exp(-2*app.t/(app.T2))+1/3;
            app.UIAxes.cla()
            app.T2EditField.Value = app.T2;
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.t,app.P_base,app.t,app.Px);
        end

        % Value changed function: T1Slider
        function T1SliderValueChanged(app, event)
            app.T1 = app.T1Slider.Value;
            app.T1EditField.Value = app.T1;
            app.P_base = 1/2*exp(-2*app.t/(app.T1))+1/6*exp(-2*app.t/(app.T2))+1/3;
            app.UIAxes.cla()
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.t,app.P_base,app.t,app.Px);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.T2SliderLabel = uilabel(app.UIFigure);
            app.T2SliderLabel.Text = 'T2';
            app.T2Slider = uislider(app.UIFigure);
            app.T2Slider.Limits = [0 500];
            app.T2Slider.ValueChangedFcn = createCallbackFcn(app, @T2SliderValueChanged, true);
            app.T1SliderLabel = uilabel(app.UIFigure);
            app.T1SliderLabel.Text = 'T1';
            app.T1Slider = uislider(app.UIFigure);
            app.T1Slider.ValueChangedFcn = createCallbackFcn(app, @T1SliderValueChanged, true);
            app.T1EditFieldLabel = uilabel(app.UIFigure);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.UIFigure, 'numeric');
            app.T2EditFieldLabel = uilabel(app.UIFigure);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Base_correct(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end