classdef Interactivefitting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        UIAxes          matlab.ui.control.UIAxes
        Ae3SliderLabel  matlab.ui.control.Label
        Ae3Slider       matlab.ui.control.Slider
        Be3SliderLabel  matlab.ui.control.Label
        Be3Slider       matlab.ui.control.Slider
    end

    methods (Access = private)

        % Value changed function: Ae3Slider
        function Ae3SliderValueChanged(app, event)
            value = app.Ae3Slider.Value;
            t = (0.01:0.01:10)';
            Px = Cal_Px(t,[value,app.Be3Slider.Value]);
            plot(app.UIAxes,t,Px);
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 1];
        end

        % Value changed function: Be3Slider
        function Be3SliderValueChanged(app, event)
            value = app.Be3Slider.Value;
            t = (0.01:0.01:10)';
            Px = Cal_Px(t,[app.Ae3Slider.Value,value]);
            plot(app.UIAxes,t,Px);
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 1];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Ae3SliderLabel = uilabel(app.UIFigure);
            app.Ae3SliderLabel.Text = 'A(e-3)';
            app.Ae3Slider = uislider(app.UIFigure);
            app.Ae3Slider.Limits = [-100 100];
            app.Ae3Slider.ValueChangedFcn = createCallbackFcn(app, @Ae3SliderValueChanged, true);
            app.Be3SliderLabel = uilabel(app.UIFigure);
            app.Be3SliderLabel.Text = 'B(e-3)';
            app.Be3Slider = uislider(app.UIFigure);
            app.Be3Slider.Limits = [-100 100];
            app.Be3Slider.ValueChangedFcn = createCallbackFcn(app, @Be3SliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interactivefitting

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end